classdef jumbf_type_checker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        AnalyzeButton          matlab.ui.control.Button
        Image_2                matlab.ui.control.Image
        UploadButton           matlab.ui.control.Button
        JUMBFTypeCheckerLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dhf; % Description
    end
    
    properties (Access = private)
        fileData % Description
        output_text = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg');
            if isequal(file,0)
                msgbox('User selected Cancel');
            else
                msgbox(['User selected ', fullfile(path,file)]);
                filename = fullfile(path,file);
          
                fileID = fopen(filename, 'rb');
                if fileID == -1
                    error('File not found');
                end
                data = uint8(fread(fileID));
                fclose(fileID);
                app.fileData = data;
                %% Check for FILE, is it JPEG or Not?
                soi_marker = typecast(data(1:2), 'uint16');

                if(~strcmp(dec2hex(soi_marker),'D8FF'))
                    uialert(app.UIFigure, 'Error', 'The File is not JPEG.');
                    error('File is Not JPEG');
                end
                I=imread(filename);
                imshow(I,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
             % DosomethingButtonPushed() prepares an embedded waitbar
            % within the uibutton and draws random dots within the
            % axes.

            % Store original button text
            originalButtonText = app.AnalyzeButton.Text;
            % When this callback function ends, return the original button state
            cleanup = onCleanup(@()set(app.AnalyzeButton,'Text',originalButtonText,'Icon',''));
            % Change button text to "Processing"
            app.AnalyzeButton.Text = 'Analyzing...';
            % Put text on top of icon
            app.AnalyzeButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.AnalyzeButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar in the button
            app.AnalyzeButton.Icon = wbar;

            %% Parse file and Collect APP11 Buffers;
            image_size = length(app.fileData);
            app11_bufs={};

            go = true;
            idx = double(3);
            app11_count = 0;
            while (go)
                last = app.fileData(idx);
                next = app.fileData(idx+1);
                marker = swapbytes(typecast(app.fileData(idx:idx+1), 'uint16'));
                marker = dec2hex(marker);
                % disp(['Marker : ' marker])
                idx= double(idx+2);
                if(last ~= 255)
                    disp("Expected A Marker")
                    break;
                elseif(next == 218) % SOS Marker
                    % disp("         SOS Marker")
                    go = false;
                    % break;
                elseif(next == 235) % APP11 Marker
                    % disp("        APP11 Marker Detected");
                    len = double(swapbytes(typecast(app.fileData(idx:idx+1), 'uint16')));
                    app11_count = app11_count+1;
                    app11_bufs{app11_count} = app.fileData(idx-2:idx+len);
                    idx = idx + len;
                elseif(strcmp(marker, 'FFD9'))
                    go = false;
                else
                    len = double(swapbytes(typecast(app.fileData(idx:idx+1), 'uint16')));
                    idx = idx + len;
                end
            end

            disp(['Total APP11 Markers Found : ', num2str(app11_count)])


            %% Contcat Boxes Distributed in different APP11 Marker.

            box_buffs={};

            app11_buf = app11_bufs{1};
            len = double(swapbytes(typecast(app11_buf(3:4), 'uint16')));
            CI = double(swapbytes(typecast(app11_buf(5:6), 'uint16')));
            PreviousEn = double(swapbytes(typecast(app11_buf(7:8), 'uint16')));
            PreviousZ = double(swapbytes(typecast(app11_buf(9:12), 'uint32')));
            box_buffs{1} = app11_buf(13:end);

            box_count = 1;
            for app11=2:app11_count
                app11_buf = app11_bufs{app11};
                len = double(swapbytes(typecast(app11_buf(3:4), 'uint16')));
                CI = double(swapbytes(typecast(app11_buf(5:6), 'uint16')));
                thisEn = double(swapbytes(typecast(app11_buf(7:8), 'uint16')));
                ThisZ = double(swapbytes(typecast(app11_buf(9:12), 'uint32')));
                if(thisEn == PreviousEn && (ThisZ == PreviousZ+1) ) % the same box
                    box_buffs{box_count} = [box_buffs{box_count}; app11_buf(21:end)];
                else
                    box_count = box_count + 1;
                    box_buffs{box_count} = app11_buf(13:end);
                end
                PreviousZ = ThisZ;
                PreviousEn = thisEn;
            end
            disp(['Total Boxes Found : ', num2str(box_count)])


            %% JUMBF Types
            jumbf_types = { ...
                {"Contigous Codestream",  [ '65', '79', 'D6', 'FB', 'DB', 'A2', '44', '6B', 'B2', 'AC', '1B', '82', 'FE', 'EB', '89', 'D1' ] } ...
                {"XML",                   [ '78', '6D', '6C', '20', '00', '11', '00', '10', '80', '00', '00', 'AA', '00', '38', '9B', '71' ] } ...
                {"JSON",                  [ '6A', '73', '6F', '6E', '00', '11', '00', '10', '80', '00', '00', 'AA', '00', '38', '9B', '71' ] } ...
                {"UUID",                  [ '75', '75', '69', '64', '00', '11', '00', '10', '80', '00', '00', 'AA', '00', '38', '9B', '71' ] } ...
                {"Embedded File",         [ '40', 'CB', '0C', '32', 'BB', '8A', '48', '9D', 'A7', '0B', '2A', 'D6', 'F4', '7F', '43', '69' ] } ...
                {"CBOR",                  [ '63', '62', '6F', '72', '00', '11', '00', '10', '80', '00', '00', 'AA', '00', '38', '9B', '71' ] } ...
                {"JPEG 360",              [ '78', '5F', '34', 'B7', '5D', '4B', '47', '4C', 'B8', '9F', '1D', '99', 'E0', 'E3', 'A8', 'DD' ] } ...
                {"JPEG Snack",            [ '16', 'AD', '91', 'E0', 'A3', '7F', '11', 'EB', '9D', '0D', '08', '00', '20', '0C', '9A', '66' ] } ...
                {"JPEG Privacy and Security : Protection Box",         [ '74', 'B1', '1B', 'BF', 'F2', '1D', '4E', 'EA', '98', 'C1', '0B', 'EB', 'F2', '3A', 'EF', 'D3' ] } ... %As specified by ISO/IEC 19566-4
                {"JPEG Privacy and Security : Replacement Box",        [ 'DC', '28', 'B9', '5F', 'B6', '8A', '49', '8E', '80', '64', '0F', 'CA', '84', '5D', '6B', '0E' ] } ... %As specified by ISO/IEC 19566-4
                };
            no_of_jumbf_types = length(jumbf_types);

            %% Check Each Box for JUMBF
            if(no_of_jumbf_types == 0)
                app.output_text = "The File does not contain JUMBF Data\n";
            else
                app.output_text = "The File contain the Following JUMBF Data\n";
                for boxNo = 1: box_count
                    Lbox = double(swapbytes(typecast(box_buffs{boxNo}(1:4), 'uint32')));
                    TBOX = box_buffs{boxNo}(5:8);
                    Tbox = string(char(TBOX'));
                    if(strcmp(Tbox , "jumb"))
                        jumd_uuid = (box_buffs{boxNo}(17:32))';
                        uuid = dec2hex(jumd_uuid);
                        uuid = reshape(uuid', 1, []);
                        for jumbNo = 1: no_of_jumbf_types
                            jumb_type = cell2mat(jumbf_types{jumbNo}(2));
                            if(isequal(uuid, jumb_type))
                                fprintf("Box No : %02d, => JUMBF Content Type : %s\n", boxNo, string(jumbf_types{jumbNo}(1)));
                                new_line = sprintf("\n JUMBF Data No : %02d  => JUMBF Content Type : %s.", boxNo, string(jumbf_types{jumbNo}(1)));
                                app.output_text = app.output_text + new_line;
                            end
                        end
                    else
                        fprintf("\n Box No : %02d, => Lbox : %010d,  => Tbox : %s", boxNo, Lbox, Tbox);
                    end
                end
            end
            app.TextArea.Value = app.output_text;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'app_files', 'logo.png');
            app.JUMBFTypeCheckerLabel = uilabel(app.Panel);
            app.JUMBFTypeCheckerLabel.Text = 'JUMBF Type Checker';
            app.UploadButton = uibutton(app.Panel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'app_files', '56-document-outline.gif');
            app.UploadButton.Text = 'Upload';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'app_files', 'logo.png');
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Icon = fullfile(pathToMLAPP, 'app_files', '212-arrow-1-rounded.gif');
            app.AnalyzeButton.Text = 'Analyze';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jumbf_type_checker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end